classdef r3d3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        R3D3UIFigure             matlab.ui.Figure
        EndEffectorPanel         matlab.ui.container.Panel
        RotationEditFieldLabel   matlab.ui.control.Label
        RotationEditField        matlab.ui.control.EditField
        TargetmEditFieldLabel    matlab.ui.control.Label
        TargetmEditField         matlab.ui.control.EditField
        AddButton                matlab.ui.control.Button
        RandomButton             matlab.ui.control.Button
        JointAnglesPanel         matlab.ui.container.Panel
        q6EditFieldLabel         matlab.ui.control.Label
        q6EditField              matlab.ui.control.NumericEditField
        q3EditFieldLabel         matlab.ui.control.Label
        q3EditField              matlab.ui.control.NumericEditField
        q5EditFieldLabel         matlab.ui.control.Label
        q5EditField              matlab.ui.control.NumericEditField
        q4EditFieldLabel         matlab.ui.control.Label
        q4EditField              matlab.ui.control.NumericEditField
        q2EditFieldLabel         matlab.ui.control.Label
        q2EditField              matlab.ui.control.NumericEditField
        q1EditFieldLabel         matlab.ui.control.Label
        q1EditField              matlab.ui.control.NumericEditField
        OptionsPanel             matlab.ui.container.Panel
        VelocitySliderLabel      matlab.ui.control.Label
        VelocitySlider           matlab.ui.control.Slider
        TaskDropDownLabel        matlab.ui.control.Label
        TaskDropDown             matlab.ui.control.DropDown
        LoadButton               matlab.ui.control.Button
        EasingSliderLabel        matlab.ui.control.Label
        EasingSlider             matlab.ui.control.Slider
        SamplingEditFieldLabel   matlab.ui.control.Label
        SamplingEditField        matlab.ui.control.NumericEditField
        stepssLabel              matlab.ui.control.Label
        msLabel                  matlab.ui.control.Label
        TabGroup                 matlab.ui.container.TabGroup
        MainTab                  matlab.ui.container.Tab
        StartButton              matlab.ui.control.Button
        ModeConfig               matlab.ui.control.RockerSwitch
        ExecuteButton            matlab.ui.control.Button
        SpaceConfig              matlab.ui.control.RockerSwitch
        ToolsTab                 matlab.ui.container.Tab
        ForceUpdateButton        matlab.ui.control.Button
        OpenModelButton          matlab.ui.control.Button
        SettingsTab              matlab.ui.container.Tab
        FKThroughputCheckBox     matlab.ui.control.CheckBox
        EnablePauseCheckBox      matlab.ui.control.CheckBox
        PausetimeEditFieldLabel  matlab.ui.control.Label
        PausetimeEditField       matlab.ui.control.NumericEditField
        EnableUDPCheckBox        matlab.ui.control.CheckBox
        DisableEasingCheckBox    matlab.ui.control.CheckBox
        TargetsListBoxLabel      matlab.ui.control.Label
        TargetsListBox           matlab.ui.control.ListBox
        RemoveButton             matlab.ui.control.Button
        ClearButton              matlab.ui.control.Button
        JointConfigurationPanel  matlab.ui.container.Panel
        ShoulderSwitchLabel      matlab.ui.control.Label
        ShoulderSwitch           matlab.ui.control.RockerSwitch
        ElbowSwitchLabel         matlab.ui.control.Label
        ElbowSwitch              matlab.ui.control.RockerSwitch
        ProgressGaugeLabel       matlab.ui.control.Label
        ProgressGauge            matlab.ui.control.LinearGauge
        LogTextAreaLabel         matlab.ui.control.Label
        LogTextArea              matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public, SetObservable)
        targets;
        orientations;
        mode = 'inverse';
        model; % top level model name
        modelPathl; % full path to the model
        isRunning; % model is running
        targetPosition; % Target Position
        currentPosition;
        currentOrientation;
        q; %joint angles
        count = 0;
        udpr; % UDP Receiver object
        udps; % UDP Sender object
        trajectory;
        n = 1000;
    end
    
    methods (Access = public)
        
        function traj = GetTrajectory(app)
            traj = lin_trajectory(app.currentPosition, ...
                app.targetPosition, ...
                app.n);
            
            % Also store it as a property
            app.trajectory = traj;
        end
        
    end
    
    methods (Access = private)
        
        function LogLn(app,str)
            app.LogTextArea.Value =  [str; app.LogTextArea.Value];
        end
        
        % Configures the model with the correct callbacks
        function ConfigureModel(app)
            load_system(app.model);
            set_param(app.model,'PostLoadFcn','cd(fileparts(which(bdroot)))')
            set_param(app.model,'InitFcn','addpath(genpath(''../''));arm_datafile');
            save_system(app.model);
        end
        
        function StartSimulation(app,event)
            set_param(app.model, 'SimulationCommand', 'start');
        end
        
        function StopSimulation(app)
            set_param(app.model, 'SimulationCommand', 'stop');
        end
        
        % Returns vector form of the target position
        function pos = GetTargetPosition(app)
            pos = str2num(app.TargetmEditField.Value);
        end
        
        function q_out = GetTargetAngles(app)
            q_out(1) = app.q1EditField.Value;
            q_out(2) = app.q2EditField.Value;
            q_out(3) = app.q3EditField.Value;
            q_out(4) = app.q4EditField.Value;
            q_out(5) = app.q5EditField.Value;
            q_out(6) = app.q6EditField.Value;
        end
        
        function WriteJointAnglesToFields(app,q_in)
            app.q1EditField.Value = q_in(1);
            app.q2EditField.Value = q_in(2);
            app.q3EditField.Value = q_in(3);
            app.q4EditField.Value = q_in(4);
            app.q5EditField.Value = q_in(5);
            app.q6EditField.Value = q_in(6);
        end
        
        function OpenUDPSocket(app)
            app.udpr = dsp.UDPReceiver('LocalIPPort',31000);
            app.udps = dsp.UDPSender('RemoteIPPort',31000);
            LogLn(app,'UDP Sockets opened');
            
        end
        
        function V = GetTargetOrientation(app)
            V = str2num(app.RotationEditField.Value);
        end
        
        function WritePosotionToField(app,pos)
            p = round(pos.',3);
            s1 = num2str(p(1));
            s2 = num2str(p(2));
            s3 = num2str(p(3));
            app.TargetmEditField.Value = ['[ ' s1 ',' s2 ',' s3 ' ]'];
        end
        
        function WriteOrientationToField(app,V)
            s1 = num2str(V(1));
            s2 = num2str(V(2));
            s3 = num2str(V(3));
            app.RotationEditField.Value = ['[ ' s1 ',' s2 ',' s3 ' ]'];
        end
        
        % Add position and orientation to targets list
        function AddTask(app,pos,ori)
            % Get current items in targets list
            items = app.TargetsListBox.Items;
            itemsData = app.TargetsListBox.ItemsData;
            
            % Track the indeces of the items
            i = length(items)+1;
            
            % Store position and orientation in object
            app.targets(end+1,:) = pos;
            app.orientations(end+1,:) = ori;
            
            posString = strjoin(arrayfun(@(x) num2str(x),pos,'UniformOutput',false),',');
            oriString = strjoin(arrayfun(@(x) num2str(x),ori,'UniformOutput',false),',');
            item = ['[' posString '] [' oriString ']' ];
            items{end+1} = item;
            LogLn(app,['Added item ' item]);
            app.TargetsListBox.Items = items;
            app.TargetsListBox.ItemsData = [itemsData i];
        end
        
        function cfg = GetCfg(app)
            shoulder = app.ShoulderSwitch.Value;
            elbow = app.ElbowSwitch.Value;
            cfg = lower([elbow(1) shoulder(1)]);
        end
        
        function RunForwardKinematics(app)
            q_i = GetTargetAngles(app);
            LogLn(app,'Running forward kinematics mode');
            if(~app.FKThroughputCheckBox.Value)
                LogLn(app,'Forward Kinematics with inverse kinematics. Overriding wrist angle!')
                [pos, T] = forwardKinematics(q_i);
                cfg = GetCfg(app);
                ori = GetTargetOrientation(app);
                
                Trot = T(1:3,1:3);
                V = round(rotationMatrixToVector(Trot)/pi,3);
                WritePosotionToField(app,pos);
                WriteOrientationToField(app,V);
                
                q_i = inverseKinematics(pos,ori,getDH(),cfg);
            end
            Broadcast(app,q_i);
        end
        
        % Sends the joint angles over UDP to the visualizer
        function Broadcast(app,q)
            if(app.EnableUDPCheckBox.Value)
                % Send to Simulink Model over UDP and pause
                app.udps(q);
                if(app.EnablePauseCheckBox.Value)
                    pause(app.PausetimeEditField.Value);
                end
            end
        end
        
        function [pos, ornt] = GetRandomConfiguration(app,n)
            
            isReachable = 0;
            DH = getDH();
            cfg = GetCfg(app);
            idx = 0;
            LogLn(app,'Generating random positions within shell');
            while(~isReachable && idx< 100)
                % Generate 8 points inside a sphere of radius [r1 r2] meters
                [x,y,z] = pointsInSphere(0.3,0.9,n);
                pos = [x y z];
                ornt = 4*rand(n,3)-2;
                for i = 1:size(pos,1)
                    q_i = inverseKinematics(pos(i,:),ornt(i,:),DH,cfg);
                    if(sum(isnan(q_i)))
                        isReachable = 0;
                        LogLn(app,'Point not reachable. Retrying!');
                    else
                        isReachable = 1;
                    end
                end
                idx = idx+1;
            end
            if(~isReachable)
                str = 'Could not find random reachable points';
                LogLn(app,str);
                uialert(app.R3D3UIFigure, ...
                    str, ...
                    'Kinematics Error');
                return
            end
        end
        
        
    end
    

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Only necessary when opened from the GUI dir
            addpath('libraries/');
            LogLn(app,'Path Added');
            
            
            OpenUDPSocket(app);
            
            app.currentPosition = forwardKinematics([0 0 0 0 0 0]);
            LogLn(app,'Set current angles to [0 0 0 0 0 0]')
            
            
            % Get this from forward kinematics
            app.currentOrientation = [0 0.5 0];
            LogLn(app,'Set current orientation to [0 0.5 0]');
            
            
        end

        % Button pushed function: AddButton
        function AddButtonPushed(app, event)
            
            % Get the position and orientation of the EEF
            pos = GetTargetPosition(app);
            ori = GetTargetOrientation(app);
            cfg = GetCfg(app);
            q_i = inverseKinematics(pos,ori,getDH(),cfg);
            
            if(find(isnan(q_i)))
                str = sprintf('[ %s ] can not be reached.',num2str(pos));
                LogLn(app,str);
                uialert(app.R3D3UIFigure, ...
                    str, ...
                    'Kinematics Error');
                return
            end
            AddTask(app,pos,ori);
        end

        % Button pushed function: ClearButton
        function ClearButtonPushed(app, event)
            LogLn(app,'Target list purged');
            app.targets = [];
            app.orientations = [];
            app.TargetsListBox.Items = {};
            app.TargetsListBox.ItemsData = [];
        end

        % Button pushed function: ExecuteButton
        function ExecuteButtonPushed(app, event)
            
            if(strcmp(app.ModeConfig.Value,'Forward'))
                
                RunForwardKinematics(app);
                
                return
            end
            
            
            if(size(app.targets,1) ~= size(app.orientations,1))
                str = 'Position and orientation vector length not equal';
                LogLn(app,str)
                error(str);
            end
            
            if(isempty(app.targets))
                app.targets = [0.5 0 0.5; 0.1 0.3 0.1; 0.4 0.3 0.6];
                app.orientations = app.targets;
                str = 'No target positions defined. Defaulting';
                LogLn(app,str);
                uialert(app.R3D3UIFigure,str,'Kinematics Error')
            end
            
            % Trajectory settings
            vMax = app.VelocitySlider.Value;
            ease = app.EasingSlider.Value/34;
            samplingRate = app.SamplingEditField.Value;
            
            DH = getDH(); % standalone function
            
            % Determine if elbow or shoulder is up down left right
            cfg = GetCfg(app);
            
            
            % Trajectory
            LogLn(app,'Generating trajectory');
            
            positions = [app.currentOrientation;app.targets];
            ornts = [app.currentOrientation; app.orientations];
            
            if(contains(app.SpaceConfig.Value,'joint','IgnoreCase',true))
                LogLn(app,'Running joint space trajectory planning');
                [app.q, traj, ornt] = positionTrajectory2(positions,samplingRate,vMax,cfg,ornts,0);
            else
                
                [traj, seg, lin] = positionTrajectory(app.currentPosition, ...
                    app.targets, ...
                    samplingRate,vMax,ease,1);
                
                % Orientation
                LogLn(app,'Generating orientation trajectory');
                ornt = orientationTrajectory(app.currentOrientation, ...
                    app.orientations, ...
                    0, ...
                    seg,1);
                
                if(isempty(traj) || isempty(ornt))
                    str = 'Trajectory empty! Did you add a new point?';
                    LogLn(app,str);
                    uialert(app.R3D3UIFigure, ...
                        str, ...
                        'Kinematics Error');
                    return
                end
                
                if(app.DisableEasingCheckBox.Value)
                    traj = lin;
                    % prototype optimized version... might be buggy
                    %                 traj = linearTrajectory(app.currentPosition, ...
                    %                     app.targets, ...
                    %                     samplingRate, vMax);
                end
                
                % Inverse Kinematics
                LogLn(app,'Calculating inverse kinematics');
                app.q = zeros(6,size(traj,1));
                for i = 1:size(traj,1)
                    app.q(:,i) = inverseKinematics(traj(i,:),ornt(i,:),DH,cfg);
                    if(find(isnan(app.q(:,i))))
                        str = sprintf('[ %s ] can not be reached.',num2str(traj(i,:)));
                        LogLn(app,str);
                        
                        uialert(app.R3D3UIFigure, ...
                            str, ...
                            'Kinematics Error');
                        return
                    end
                end
            end
            postProcess(app.q,samplingRate);
            
            % Iterate over joint space
            LogLn(app,'Broadcasting over UDP')
            for i = 1:size(app.q,2)
                % Only update every 50 steps
                if(mod(i,50) == 0)
                    WriteJointAnglesToFields(app,app.q(:,i));
                    WritePosotionToField(app,traj(i,:));
                    app.ProgressGauge.Value = i/size(app.q,2)*100;
                end
                
                % Send value over UDP
                Broadcast(app,app.q(:,i));
            end
            
            app.ProgressGauge.Value = 100;
            
            % Final position is our current position and orientation
            app.currentPosition = traj(end,:);
            app.currentOrientation = ornt(end,:);


        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % First clear the targets list
            ClearButtonPushed(app,event);
            
            file =app.TaskDropDown.Value;
            
            if(~exist(file,'file') && ~strcmp('randomize',file))
                str = sprintf('The file %s does not exist!',file)
                uialert(app.R3D3UIFigure, ...
                    str, ...
                    'File Error');
                return
            end
            
            if(strcmp('randomize',file))
                pos = zeros(8,3);
                ornt = zeros(8,3);
                [pos, ornt] = GetRandomConfiguration(app,8);
                S.positions = pos;
                S.orientations = ornt;
                
            else
                LogLn(app,['Importing data from ' file]);
                S = importdata(file);
            end
            
            for i = 1:size(S.positions,1)
                AddTask(app,S.positions(i,:),S.orientations(i,:))
            end
            
        end

        % Value changed function: ModeConfig
        function ModeConfigValueChanged(app, event)
            app.mode = lower(app.ModeConfig.Value);
            
        end

        % Button pushed function: OpenModelButton
        function OpenModelButtonPushed(app, event)
            [filename,pathname] = uigetfile('*.slx','Select the robot arm model');
            
            if isequal(filename,0)
                disp('User selected Cancel')
            else
                % Only open the model if the user actually selected a file
                disp(['User selected ', fullfile(pathname, filename)])
                app.modelPath = fullfile(pathname, filename);
                cd(pathname);
                open_system(app.modelPath);
                
                % Store top level model name
                app.model = bdroot;
                
                % Configure the model once its opened
                ConfigureModel(app);
            end
            
            
        end

        % Button pushed function: RandomButton
        function RandomButtonPushed(app, event)
            [pos, ornt] = GetRandomConfiguration(app,1);
            WriteOrientationToField(app,ornt);
            WritePosotionToField(app,pos);
            
        end

        % Button pushed function: RemoveButton
        function RemoveButtonPushed(app, event)
            i = app.TargetsListBox.Value;
            LogLn(app,['Removing entry #' i]);
            if(isempty(i))
                return
            end
            
            app.targets(i,:) = [];
            app.orientations(i,:) = [];
            app.TargetsListBox.Items(i) = [];
            
            app.TargetsListBox.ItemsData(i) = [];
            % Refresh the indeces
            app.TargetsListBox.ItemsData = 1:length(app.TargetsListBox.ItemsData);
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            
            if(app.isRunning)
                app.StartButton.Text = 'Start';
                StopSimulation(app);
                app.isRunning = false;
                app.ExecuteButton.Enable = 'off';
                return;
            end
            
            % Open the model if this is the first time we're running
            if(isempty(app.model))
                
                % Open the model
                OpenModelButtonPushed(app,event);
                
            end
            
            % Not sure why this is inside if-statement
            if(exist(app.modelPath,'file'))
                
                app.targetPosition = GetTargetPosition(app);
                
                WriteToModelWorkspace(app);
                StartSimulation(app);
                ExecuteButtonPushed(app,event);
                app.isRunning = true;
            end
            
            % Change button state
            if(app.isRunning)
                app.StartButton.Text = 'Stop';
                app.ExecuteButton.Enable = 'on';
            else
                % Redundant
                app.isRunning = false;
                app.StartButton.Text = 'Start';
            end
            
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.R3D3UIFigure = uifigure;
            app.R3D3UIFigure.Name = 'R3D3';
            app.EndEffectorPanel = uipanel(app.R3D3UIFigure);
            app.EndEffectorPanel.Title = 'End Effector';
            app.RotationEditFieldLabel = uilabel(app.EndEffectorPanel);
            app.RotationEditFieldLabel.Text = 'Rotation [π]';
            app.RotationEditField = uieditfield(app.EndEffectorPanel, 'text');
            app.RotationEditField.Value = '[ 0 0.5 0 ]';
            app.TargetmEditFieldLabel = uilabel(app.EndEffectorPanel);
            app.TargetmEditFieldLabel.Text = 'Target [m]';
            app.TargetmEditField = uieditfield(app.EndEffectorPanel, 'text');
            app.TargetmEditField.Value = '[ 0.5 0 0.5 ]';
            app.AddButton = uibutton(app.EndEffectorPanel, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushed, true);
            app.AddButton.Text = 'Add';
            app.RandomButton = uibutton(app.EndEffectorPanel, 'push');
            app.RandomButton.ButtonPushedFcn = createCallbackFcn(app, @RandomButtonPushed, true);
            app.RandomButton.Text = 'Random';
            app.JointAnglesPanel = uipanel(app.R3D3UIFigure);
            app.JointAnglesPanel.Title = 'Joint Angles';
            app.q6EditFieldLabel = uilabel(app.JointAnglesPanel);
            app.q6EditFieldLabel.Text = 'q6';
            app.q6EditField = uieditfield(app.JointAnglesPanel, 'numeric');
            app.q6EditField.ValueDisplayFormat = '%.3f';
            app.q3EditFieldLabel = uilabel(app.JointAnglesPanel);
            app.q3EditFieldLabel.Text = 'q3';
            app.q3EditField = uieditfield(app.JointAnglesPanel, 'numeric');
            app.q3EditField.ValueDisplayFormat = '%.3f';
            app.q5EditFieldLabel = uilabel(app.JointAnglesPanel);
            app.q5EditFieldLabel.Text = 'q5';
            app.q5EditField = uieditfield(app.JointAnglesPanel, 'numeric');
            app.q5EditField.ValueDisplayFormat = '%.3f';
            app.q4EditFieldLabel = uilabel(app.JointAnglesPanel);
            app.q4EditFieldLabel.Text = 'q4';
            app.q4EditField = uieditfield(app.JointAnglesPanel, 'numeric');
            app.q4EditField.ValueDisplayFormat = '%.3f';
            app.q2EditFieldLabel = uilabel(app.JointAnglesPanel);
            app.q2EditFieldLabel.Text = 'q2';
            app.q2EditField = uieditfield(app.JointAnglesPanel, 'numeric');
            app.q2EditField.ValueDisplayFormat = '%.3f';
            app.q1EditFieldLabel = uilabel(app.JointAnglesPanel);
            app.q1EditFieldLabel.Text = 'q1';
            app.q1EditField = uieditfield(app.JointAnglesPanel, 'numeric');
            app.q1EditField.ValueDisplayFormat = '%.3f';
            app.OptionsPanel = uipanel(app.R3D3UIFigure);
            app.OptionsPanel.Title = 'Options';
            app.VelocitySliderLabel = uilabel(app.OptionsPanel);
            app.VelocitySliderLabel.Text = 'Velocity';
            app.VelocitySlider = uislider(app.OptionsPanel);
            app.VelocitySlider.Limits = [0 10];
            app.VelocitySlider.Value = 5;
            app.TaskDropDownLabel = uilabel(app.OptionsPanel);
            app.TaskDropDownLabel.Text = 'Task';
            app.TaskDropDown = uidropdown(app.OptionsPanel);
            app.TaskDropDown.Items = {'randomize', 'fixed_8.mat'};
            app.TaskDropDown.Editable = 'on';
            app.TaskDropDown.Value = 'fixed_8.mat';
            app.LoadButton = uibutton(app.OptionsPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.EasingSliderLabel = uilabel(app.OptionsPanel);
            app.EasingSliderLabel.Text = 'Easing';
            app.EasingSlider = uislider(app.OptionsPanel);
            app.EasingSlider.Limits = [0 10];
            app.SamplingEditFieldLabel = uilabel(app.OptionsPanel);
            app.SamplingEditFieldLabel.Text = 'Sampling';
            app.SamplingEditField = uieditfield(app.OptionsPanel, 'numeric');
            app.SamplingEditField.ValueDisplayFormat = '%.0f';
            app.SamplingEditField.Value = 500;
            app.stepssLabel = uilabel(app.OptionsPanel);
            app.stepssLabel.Text = 'steps/s';
            app.msLabel = uilabel(app.OptionsPanel);
            app.msLabel.Text = 'm/s';
            app.TabGroup = uitabgroup(app.R3D3UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.StartButton = uibutton(app.MainTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Enable = 'off';
            app.StartButton.Text = 'Start';
            app.ModeConfig = uiswitch(app.MainTab, 'rocker');
            app.ModeConfig.Items = {'Inverse', 'Forward'};
            app.ModeConfig.ValueChangedFcn = createCallbackFcn(app, @ModeConfigValueChanged, true);
            app.ModeConfig.Value = 'Inverse';
            app.ExecuteButton = uibutton(app.MainTab, 'push');
            app.ExecuteButton.ButtonPushedFcn = createCallbackFcn(app, @ExecuteButtonPushed, true);
            app.ExecuteButton.Text = 'Execute';
            app.SpaceConfig = uiswitch(app.MainTab, 'rocker');
            app.SpaceConfig.Items = {'Task Space', 'Joint Space'};
            app.SpaceConfig.Value = 'Task Space';
            app.ToolsTab = uitab(app.TabGroup);
            app.ToolsTab.Title = 'Tools';
            app.ForceUpdateButton = uibutton(app.ToolsTab, 'push');
            app.ForceUpdateButton.Text = 'Force Update';
            app.OpenModelButton = uibutton(app.ToolsTab, 'push');
            app.OpenModelButton.ButtonPushedFcn = createCallbackFcn(app, @OpenModelButtonPushed, true);
            app.OpenModelButton.Text = 'Open Model';
            app.SettingsTab = uitab(app.TabGroup);
            app.SettingsTab.Title = 'Settings';
            app.FKThroughputCheckBox = uicheckbox(app.SettingsTab);
            app.FKThroughputCheckBox.Text = 'FK Throughput';
            app.EnablePauseCheckBox = uicheckbox(app.SettingsTab);
            app.EnablePauseCheckBox.Text = 'Enable Pause';
            app.EnablePauseCheckBox.Value = true;
            app.PausetimeEditFieldLabel = uilabel(app.SettingsTab);
            app.PausetimeEditFieldLabel.Text = 'Pause time';
            app.PausetimeEditField = uieditfield(app.SettingsTab, 'numeric');
            app.PausetimeEditField.Limits = [0.001 5];
            app.PausetimeEditField.Value = 0.05;
            app.EnableUDPCheckBox = uicheckbox(app.SettingsTab);
            app.EnableUDPCheckBox.Text = 'Enable UDP';
            app.EnableUDPCheckBox.Value = true;
            app.DisableEasingCheckBox = uicheckbox(app.SettingsTab);
            app.DisableEasingCheckBox.Text = 'Disable Easing';
            app.TargetsListBoxLabel = uilabel(app.R3D3UIFigure);
            app.TargetsListBoxLabel.Text = 'Targets';
            app.TargetsListBox = uilistbox(app.R3D3UIFigure);
            app.TargetsListBox.Items = {};
            app.TargetsListBox.Value = {};
            app.RemoveButton = uibutton(app.R3D3UIFigure, 'push');
            app.RemoveButton.ButtonPushedFcn = createCallbackFcn(app, @RemoveButtonPushed, true);
            app.RemoveButton.Text = 'Remove';
            app.ClearButton = uibutton(app.R3D3UIFigure, 'push');
            app.ClearButton.ButtonPushedFcn = createCallbackFcn(app, @ClearButtonPushed, true);
            app.ClearButton.Text = 'Clear';
            app.JointConfigurationPanel = uipanel(app.R3D3UIFigure);
            app.JointConfigurationPanel.Title = 'Joint Configuration';
            app.ShoulderSwitchLabel = uilabel(app.JointConfigurationPanel);
            app.ShoulderSwitchLabel.Text = 'Shoulder';
            app.ShoulderSwitch = uiswitch(app.JointConfigurationPanel, 'rocker');
            app.ShoulderSwitch.Items = {'Right', 'Left'};
            app.ShoulderSwitch.Value = 'Left';
            app.ElbowSwitchLabel = uilabel(app.JointConfigurationPanel);
            app.ElbowSwitchLabel.Text = 'Elbow';
            app.ElbowSwitch = uiswitch(app.JointConfigurationPanel, 'rocker');
            app.ElbowSwitch.Items = {'Down', 'Up'};
            app.ElbowSwitch.Value = 'Up';
            app.ProgressGaugeLabel = uilabel(app.R3D3UIFigure);
            app.ProgressGaugeLabel.Text = 'Progress';
            app.ProgressGauge = uigauge(app.R3D3UIFigure, 'linear');
            app.LogTextAreaLabel = uilabel(app.R3D3UIFigure);
            app.LogTextAreaLabel.Text = 'Log';
            app.LogTextArea = uitextarea(app.R3D3UIFigure);
        end
    end

    methods (Access = public)

        % Construct app
        function app = r3d3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.R3D3UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.R3D3UIFigure)
        end
    end
end